classdef Sampler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        Stop                    matlab.ui.control.Button
        Play                    matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        Repetir                 matlab.ui.control.Button
        xVecesLabel             matlab.ui.control.Label
        Monstruo                matlab.ui.control.Button
        Robot                   matlab.ui.control.Button
        Retardo                 matlab.ui.control.NumericEditField
        RetardosEditFieldLabel  matlab.ui.control.Label
        DarthVader              matlab.ui.control.Button
        Velocidad               matlab.ui.control.NumericEditField
        VelocidadvecesLabel     matlab.ui.control.Label
        Volumen                 matlab.ui.control.NumericEditField
        VolumenEditFieldLabel   matlab.ui.control.Label
        Guardar                 matlab.ui.control.Button
        Grabar                  matlab.ui.control.Button
        Abrir                   matlab.ui.control.Button
        EditarPanel             matlab.ui.container.Panel
        EfectosPanel            matlab.ui.container.Panel
        ArchivoPanel            matlab.ui.container.Panel
        Grafica                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AppGuardar % La app que guarda el archivo
        AppRepetir % La app que repite una seccion
        AppGrabar  % La app que graba
        AppVelocidad % La app que ajusta la velocidad
        Reproductor % El reproductor de audio
        Inicio
        Fin
        Veces
    end
    
    methods (Access = public)
        
        function graficar(app)
            load("datos.mat","s","fs");
            t = 0:1/fs:length(s)/fs;
            t = seconds(t);
            t = t(1:end-1);
            t = transpose(t);
            plot(app.Grafica, t, s);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isfile("datos.mat")
                delete("datos.mat");
            end
            if isfile("repetir.mat")
                delete("repetir.mat");
            end
        end

        % Button pushed function: Abrir
        function AbrirButtonPushed(app, event)
            % Si ya existia un archivo con datos de audio, se borra
            if isfile("datos.mat")
                delete("datos.mat");
            end
            % Abre un cuadro de dialogo para seleccionar un archivo
            app.Abrir.Enable = "off";
            [archivo] = uigetfile(["*.wav";"*.mp3";"*."], 'Select a File');
            app.Abrir.Enable = "on";
            % archivo recibe un valor. Si se selecciono un archivo, 
            % contiene texto. Sino, contiene un valor numérico (0)
            if ischar(archivo)
                %Lee el archivo, y asigna los datos del audio a s
                %Y la frecuencia de muestreo a fs
                [s, fs] = audioread(archivo);
                % Guarda los datos en un archivo de señal
                save("datos.mat","s", "fs");
                save("original.mat", "s", "fs");
                app.graficar();
            end
        end

        % Button pushed function: Guardar
        function GuardarButtonPushed(app, event)
            app.Guardar.Enable = "off";
            app.AppGuardar = guardar(app);
        end

        % Button pushed function: Repetir
        function RepetirPushed(app, event)
            app.Repetir.Enable = "off";
            app.AppRepetir = repetir(app);
            app.graficar();
        end

        % Button pushed function: Grabar
        function GrabarButtonPushed(app, event)
            app.Grabar.Enable = "off";
            app.AppGrabar = grabar(app);
            app.graficar();
        end

        % Button pushed function: Play
        function PlayButtonPushed2(app, event)
            Efectos.modificar(app.Volumen.Value,app.Velocidad.Value,app.Retardo.Value);
            load("datos.mat", "s", "fs");
            app.graficar();
            app.Reproductor = audioplayer(s,fs);
            play(app.Reproductor);
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            stop(app.Reproductor);
        end

        % Button pushed function: DarthVader
        function DarthVaderButtonPushed(app, event)
            app.Velocidad.Value = 0.78;
            app.Retardo.Value = 0.06;
            Efectos.modificar(app.Volumen.Value,app.Velocidad.Value,app.Retardo.Value);
            app.graficar();
        end

        % Button pushed function: Robot
        function RobotButtonPushed(app, event)
            app.Velocidad.Value = 1.4;
            app.Retardo.Value = 0.08;
            Efectos.modificar(app.Volumen.Value,app.Velocidad.Value,app.Retardo.Value);
            app.graficar();
        end

        % Button pushed function: Monstruo
        function MonstruoButtonPushed(app, event)
            app.Velocidad.Value = 0.69;
            app.Retardo.Value = 0;
            Efectos.modificar(app.Volumen.Value,app.Velocidad.Value,app.Retardo.Value);
            app.graficar();
        end

        % Value changed function: Velocidad
        function VelocidadValueChanged(app, event)
            Efectos.modificar(app.Volumen.Value,app.Velocidad.Value,app.Retardo.Value);
            app.graficar();
        end

        % Value changed function: Volumen
        function VolumenValueChanged(app, event)
            Efectos.modificar(app.Volumen.Value,app.Velocidad.Value,app.Retardo.Value);
            app.graficar();
        end

        % Value changed function: Retardo
        function RetardoValueChanged(app, event)
            Efectos.modificar(app.Volumen.Value,app.Velocidad.Value,app.Retardo.Value);
            app.graficar();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica = uiaxes(app.UIFigure);
            xlabel(app.Grafica, 'Tiempo(s)')
            ylabel(app.Grafica, 'Amplitud')
            zlabel(app.Grafica, 'Z')
            app.ArchivoPanel = uipanel(app.UIFigure);
            app.ArchivoPanel.Title = 'Archivo';
            app.EfectosPanel = uipanel(app.UIFigure);
            app.EfectosPanel.Title = 'Efectos';
            app.EditarPanel = uipanel(app.UIFigure);
            app.EditarPanel.Title = 'Editar';
            app.Abrir = uibutton(app.UIFigure, 'push');
            app.Abrir.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.Abrir.Icon = fullfile(pathToMLAPP, '6700002.png');
            app.Abrir.Text = 'Abrir...';
            app.Grabar = uibutton(app.UIFigure, 'push');
            app.Grabar.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.Grabar.Text = 'Grabar...';
            app.Guardar = uibutton(app.UIFigure, 'push');
            app.Guardar.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.Guardar.Text = 'Guardar...';
            app.VolumenEditFieldLabel = uilabel(app.UIFigure);
            app.VolumenEditFieldLabel.Text = 'Volumen';
            app.Volumen = uieditfield(app.UIFigure, 'numeric');
            app.Volumen.ValueChangedFcn = createCallbackFcn(app, @VolumenValueChanged, true);
            app.Volumen.Value = 100;
            app.VelocidadvecesLabel = uilabel(app.UIFigure);
            app.VelocidadvecesLabel.Text = 'Velocidad (veces)';
            app.Velocidad = uieditfield(app.UIFigure, 'numeric');
            app.Velocidad.Limits = [0 Inf];
            app.Velocidad.ValueChangedFcn = createCallbackFcn(app, @VelocidadValueChanged, true);
            app.Velocidad.Value = 1;
            app.DarthVader = uibutton(app.UIFigure, 'push');
            app.DarthVader.ButtonPushedFcn = createCallbackFcn(app, @DarthVaderButtonPushed, true);
            app.DarthVader.Text = 'Darth Vader';
            app.RetardosEditFieldLabel = uilabel(app.UIFigure);
            app.RetardosEditFieldLabel.Text = 'Retardo (s)';
            app.Retardo = uieditfield(app.UIFigure, 'numeric');
            app.Retardo.ValueChangedFcn = createCallbackFcn(app, @RetardoValueChanged, true);
            app.Robot = uibutton(app.UIFigure, 'push');
            app.Robot.ButtonPushedFcn = createCallbackFcn(app, @RobotButtonPushed, true);
            app.Robot.Text = 'Robot';
            app.Monstruo = uibutton(app.UIFigure, 'push');
            app.Monstruo.ButtonPushedFcn = createCallbackFcn(app, @MonstruoButtonPushed, true);
            app.Monstruo.Text = 'Monstruo';
            app.xVecesLabel = uilabel(app.UIFigure);
            app.xVecesLabel.Text = 'x';
            app.Repetir = uibutton(app.UIFigure, 'push');
            app.Repetir.ButtonPushedFcn = createCallbackFcn(app, @RepetirPushed, true);
            app.Repetir.Text = 'Repetir';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed2, true);
            app.Play.Icon = fullfile(pathToMLAPP, 'png-clipart-computer-icons-youtube-play-button-button-angle-text.png');
            app.Play.Text = '';
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Icon = fullfile(pathToMLAPP, 'stop_circle_outline_icon_139164.png');
            app.Stop.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo Final Alkaeda.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sampler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end