classdef grabar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        sec                          matlab.ui.control.Label
        Segundos                     matlab.ui.control.Label
        VistaPreviaButton            matlab.ui.control.Button
        FdeMuestreo                  matlab.ui.control.NumericEditField
        FrecuenciademuestreoHzLabel  matlab.ui.control.Label
        ACEPTARButton                matlab.ui.control.Button
        CANCELARButton               matlab.ui.control.Button
        min                          matlab.ui.control.Label
        Minutos                      matlab.ui.control.Label
        Texto                        matlab.ui.control.Label
        Detener                      matlab.ui.control.Button
        Grabar                       matlab.ui.control.Button
        GRABARAUDIOLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppPrincipal % Description
        Grabadora
        grabando
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, llamadora)
            app.AppPrincipal = llamadora;
            app.Grabadora = audiorecorder(app.FdeMuestreo.Value,16,2);
        end

        % Button pushed function: ACEPTARButton
        function ACEPTARButtonPushed(app, event)
            s = getaudiodata(app.Grabadora);
            if( ~isempty(s) )
                %Guarda el audio grabado
                fs = get(app.Grabadora, "SampleRate");
                save("datos.mat", "s", "fs");
                %Activa el boton Grabar... y cierra la app
                app.AppPrincipal.Grabar.Enable = "on";
                app.AppPrincipal.graficar();
                delete(app);
            end
        end

        % Button pushed function: CANCELARButton
        function CANCELARButtonPushed(app, event)
            %Activa el boton Grabar... y cierra la app
            app.AppPrincipal.Grabar.Enable = "on";
            delete(app);    
        end

        % Button pushed function: Detener
        function DetenerButtonPushed(app, event)
            stop(app.Grabadora);
            disp("Grabacion Detenida.");
            app.Texto.Text = "Tiempo:";
            app.Texto.Visible = "on";
            muestras = getaudiodata(app.Grabadora);
            m = floor(length(muestras)/(44100*60));
            app.Minutos.Text = string(m);
            app.Minutos.Visible = "on";
            seg = length(muestras) - (m*44100*60);
            seg = floor(seg/44100);
            app.Segundos.Text = string(seg);
            app.Segundos.Visible = "on";
            app.min.Visible = "on";
            app.sec.Visible = "on";
            app.Grabar.BackgroundColor = [0.96 0.96 0.96];
            %Activa los botones aceptar y cancelar
            app.ACEPTARButton.Enable = "on";
            app.CANCELARButton.Enable = "on";
            %Activa y muestra la vista previa
            app.VistaPreviaButton.Visible = "on";
            app.VistaPreviaButton.Enable = "on";
        end

        % Button pushed function: Grabar
        function GrabarButtonPushed(app, event)
            %Oculta los botones aceptar y cancelar
            app.ACEPTARButton.Enable = "off";
            app.CANCELARButton.Enable = "off";
            %Oculta y desactiva la vista previa
            app.VistaPreviaButton.Visible = "off";
            app.VistaPreviaButton.Enable = "off";
            record(app.Grabadora);
            app.Grabar.BackgroundColor = [0.72 0.11 0.11];
            disp("Grabando...");
            app.Texto.Text = "Grabando...";
            app.Texto.Visible = "on";
            app.Minutos.Visible = "off";
            app.Segundos.Visible = "off";
            app.min.Visible = "off";
            app.sec.Visible = "off";
        end

        % Button pushed function: VistaPreviaButton
        function VistaPreviaButtonPushed(app, event)
            y = getaudiodata(app.Grabadora);
            fs = get(app.Grabadora, "SampleRate");
            sound(y,fs);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.AppPrincipal.Grabar.Enable = "on";
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GRABARAUDIOLabel = uilabel(app.UIFigure);
            app.GRABARAUDIOLabel.Text = 'GRABAR AUDIO';
            app.Grabar = uibutton(app.UIFigure, 'push');
            app.Grabar.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.Grabar.Icon = fullfile(pathToMLAPP, 'Record-Pressed-icon.png');
            app.Grabar.Text = '';
            app.Detener = uibutton(app.UIFigure, 'push');
            app.Detener.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.Detener.Icon = fullfile(pathToMLAPP, '189640.png');
            app.Detener.Text = '';
            app.Texto = uilabel(app.UIFigure);
            app.Texto.Visible = 'off';
            app.Texto.Text = 'Tiempo:';
            app.Minutos = uilabel(app.UIFigure);
            app.Minutos.Visible = 'off';
            app.Minutos.Text = '';
            app.min = uilabel(app.UIFigure);
            app.min.Visible = 'off';
            app.min.Text = 'min.';
            app.CANCELARButton = uibutton(app.UIFigure, 'push');
            app.CANCELARButton.ButtonPushedFcn = createCallbackFcn(app, @CANCELARButtonPushed, true);
            app.CANCELARButton.Text = 'CANCELAR';
            app.ACEPTARButton = uibutton(app.UIFigure, 'push');
            app.ACEPTARButton.ButtonPushedFcn = createCallbackFcn(app, @ACEPTARButtonPushed, true);
            app.ACEPTARButton.Enable = 'off';
            app.ACEPTARButton.Text = 'ACEPTAR';
            app.FrecuenciademuestreoHzLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoHzLabel.Text = 'Frecuencia de muestreo (Hz)';
            app.FdeMuestreo = uieditfield(app.UIFigure, 'numeric');
            app.FdeMuestreo.ValueDisplayFormat = '%.0f';
            app.FdeMuestreo.Value = 44100;
            app.VistaPreviaButton = uibutton(app.UIFigure, 'push');
            app.VistaPreviaButton.ButtonPushedFcn = createCallbackFcn(app, @VistaPreviaButtonPushed, true);
            app.VistaPreviaButton.Text = 'Vista Previa';
            app.Segundos = uilabel(app.UIFigure);
            app.Segundos.Text = '';
            app.sec = uilabel(app.UIFigure);
            app.sec.Visible = 'off';
            app.sec.Text = 's';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grabar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end