classdef repetir < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        sLabel_2                 matlab.ui.control.Label
        seg2                     matlab.ui.control.NumericEditField
        minLabel_2               matlab.ui.control.Label
        min2                     matlab.ui.control.NumericEditField
        HastaLabel               matlab.ui.control.Label
        sLabel                   matlab.ui.control.Label
        seg1                     matlab.ui.control.NumericEditField
        minLabel                 matlab.ui.control.Label
        ACEPTARButton            matlab.ui.control.Button
        CANCELARButton           matlab.ui.control.Button
        ReproducirseccionButton  matlab.ui.control.Button
        Veces                    matlab.ui.control.NumericEditField
        VecesEditFieldLabel      matlab.ui.control.Label
        min1                     matlab.ui.control.NumericEditField
        DesdeEditFieldLabel      matlab.ui.control.Label
        RepetirseccionLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppPrincipal % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, llamadora)
            app.AppPrincipal = llamadora;
        end

        % Button pushed function: CANCELARButton
        function CANCELARButtonPushed(app, event)
            app.AppPrincipal.Repetir.Enable = "on";
            delete(app);
        end

        % Button pushed function: ACEPTARButton
        function ACEPTARButtonPushed(app, event)
            %Guardar datos
            Ini = round(app.min1.Value*44100*60 + app.seg1.Value*44100);
            if(Ini == 0)
                Ini = 1;
            end
            Fin = round(app.min2.Value*44100*60 + app.seg2.Value*44100);
            if(Fin == 0)
                Fin = 1;
            end
            vez = app.Veces.Value;
            save("repetir.mat", "vez","Ini", "Fin");
            app.AppPrincipal.Repetir.Enable = "on";
            delete(app);
        end

        % Button pushed function: ReproducirseccionButton
        function ReproducirseccionButtonPushed(app, event)
            load("original.mat","s", "fs");
            Ini = round(app.min1.Value*44100*60 + app.seg1.Value*44100);
            if(Ini == 0)
                Ini = 1;
            end
            Fin = round(app.min2.Value*44100*60 + app.seg2.Value*44100);
            if(Fin == 0)
                Fin = 1;
            end
            mue = s(Ini:Fin);
            sound(mue,fs);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.AppPrincipal.Repetir.Enable = "on";
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RepetirseccionLabel = uilabel(app.UIFigure);
            app.RepetirseccionLabel.Text = 'Repetir seccion';
            app.DesdeEditFieldLabel = uilabel(app.UIFigure);
            app.DesdeEditFieldLabel.Text = 'Desde';
            app.min1 = uieditfield(app.UIFigure, 'numeric');
            app.VecesEditFieldLabel = uilabel(app.UIFigure);
            app.VecesEditFieldLabel.Text = 'Veces';
            app.Veces = uieditfield(app.UIFigure, 'numeric');
            app.ReproducirseccionButton = uibutton(app.UIFigure, 'push');
            app.ReproducirseccionButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirseccionButtonPushed, true);
            app.ReproducirseccionButton.Text = 'Reproducir seccion';
            app.CANCELARButton = uibutton(app.UIFigure, 'push');
            app.CANCELARButton.ButtonPushedFcn = createCallbackFcn(app, @CANCELARButtonPushed, true);
            app.CANCELARButton.Text = 'CANCELAR';
            app.ACEPTARButton = uibutton(app.UIFigure, 'push');
            app.ACEPTARButton.ButtonPushedFcn = createCallbackFcn(app, @ACEPTARButtonPushed, true);
            app.ACEPTARButton.Text = 'ACEPTAR';
            app.minLabel = uilabel(app.UIFigure);
            app.minLabel.Text = 'min.';
            app.seg1 = uieditfield(app.UIFigure, 'numeric');
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.HastaLabel = uilabel(app.UIFigure);
            app.HastaLabel.Text = 'Hasta';
            app.min2 = uieditfield(app.UIFigure, 'numeric');
            app.minLabel_2 = uilabel(app.UIFigure);
            app.minLabel_2.Text = 'min.';
            app.seg2 = uieditfield(app.UIFigure, 'numeric');
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = 's';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = repetir(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end