classdef guardar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NombreEditField       matlab.ui.control.EditField
        NombreEditFieldLabel  matlab.ui.control.Label
        CANCELARButton        matlab.ui.control.Button
        GUARDARButton         matlab.ui.control.Button
        GuardaraudioLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppPrincipal % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, llamadora)
            app.AppPrincipal = llamadora;
        end

        % Button pushed function: CANCELARButton
        function CANCELARButtonPushed(app, event)
            app.AppPrincipal.Guardar.Enable = "on";
            delete(app);
        end

        % Button pushed function: GUARDARButton
        function GUARDARButtonPushed(app, event)
            if(app.NombreEditField.Value ~= "")
                %Guarda el archivo
                load("datos.mat", "y", "fs");
                audiowrite(app.NombreEditField.Value, y, fs);
                app.AppPrincipal.Guardar.Enable = "on";
                delete(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.AppPrincipal.Guardar.Enable = "on";
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GuardaraudioLabel = uilabel(app.UIFigure);
            app.GuardaraudioLabel.Text = 'Guardar audio';
            app.GUARDARButton = uibutton(app.UIFigure, 'push');
            app.GUARDARButton.ButtonPushedFcn = createCallbackFcn(app, @GUARDARButtonPushed, true);
            app.GUARDARButton.Text = 'GUARDAR';
            app.CANCELARButton = uibutton(app.UIFigure, 'push');
            app.CANCELARButton.ButtonPushedFcn = createCallbackFcn(app, @CANCELARButtonPushed, true);
            app.CANCELARButton.Text = 'CANCELAR';
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guardar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end